classdef BeamlineViewer < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    LucretiaBEAMLINEUIFigure  matlab.ui.Figure
    FileMenu                  matlab.ui.container.Menu
    BeamlineDirMenu           matlab.ui.container.Menu
    LoadBeamlineMenu          matlab.ui.container.Menu
    LayoutMenu                matlab.ui.container.Menu
    XZMenu                    matlab.ui.container.Menu
    YZMenu                    matlab.ui.container.Menu
    TwissMenu                 matlab.ui.container.Menu
    beta_xMenu                matlab.ui.container.Menu
    beta_yMenu                matlab.ui.container.Menu
    eta_xMenu                 matlab.ui.container.Menu
    eta_yMenu                 matlab.ui.container.Menu
    PlotTabGroup              matlab.ui.container.TabGroup
    LayoutTab                 matlab.ui.container.Tab
    LayoutAxes                matlab.ui.control.UIAxes
    TwissTab                  matlab.ui.container.Tab
    TwissAxes                 matlab.ui.control.UIAxes
    BEAMLINEPanel             matlab.ui.container.Panel
    BLLabel_6                 matlab.ui.control.Label
    BLLabel_5                 matlab.ui.control.Label
    BLLabel_4                 matlab.ui.control.Label
    BLLabel_3                 matlab.ui.control.Label
    BLLabel_2                 matlab.ui.control.Label
    DropDown                  matlab.ui.control.DropDown
    SearchUpButton            matlab.ui.control.Button
    RESETButton               matlab.ui.control.Button
    RESIZEButton              matlab.ui.control.Button
    SearchDownButton          matlab.ui.control.Button
    SearchText                matlab.ui.control.EditField
    BEAMLINEText              matlab.ui.control.ListBox
    TabGroup                  matlab.ui.container.TabGroup
    ElementTab                matlab.ui.container.Tab
    TrackFlagLabel            matlab.ui.control.Label
    BLLabel                   matlab.ui.control.Label
    TrackFlagText             matlab.ui.control.TextArea
    ElementText               matlab.ui.control.TextArea
    PSTab                     matlab.ui.container.Tab
    PSListBox                 matlab.ui.control.ListBox
    PSText                    matlab.ui.control.TextArea
    KLYSTRONTab               matlab.ui.container.Tab
    KLYSTRONListBox           matlab.ui.control.ListBox
    KLYSTRONText              matlab.ui.control.TextArea
    GIRDERTab                 matlab.ui.container.Tab
    GIRDERListBox             matlab.ui.control.ListBox
    GIRDERText                matlab.ui.control.TextArea
    InitialTab                matlab.ui.container.Tab
    GridLayout                matlab.ui.container.GridLayout
    LoadfromWorkspaceButton   matlab.ui.control.Button
    EditField_49              matlab.ui.control.EditField
    EditField_46              matlab.ui.control.NumericEditField
    EditField_43              matlab.ui.control.NumericEditField
    EditField_40              matlab.ui.control.NumericEditField
    EditField_37              matlab.ui.control.NumericEditField
    EditField_34              matlab.ui.control.NumericEditField
    EditField_31              matlab.ui.control.NumericEditField
    EditField_28              matlab.ui.control.NumericEditField
    EditField_27              matlab.ui.control.NumericEditField
    EditField_26              matlab.ui.control.NumericEditField
    EditField_24              matlab.ui.control.NumericEditField
    EditField_23              matlab.ui.control.NumericEditField
    EditField_21              matlab.ui.control.NumericEditField
    EditField_20              matlab.ui.control.NumericEditField
    EditField_18              matlab.ui.control.NumericEditField
    EditField_17              matlab.ui.control.NumericEditField
    EditField_15              matlab.ui.control.NumericEditField
    EditField_14              matlab.ui.control.NumericEditField
    EditField_12              matlab.ui.control.NumericEditField
    EditField_11              matlab.ui.control.NumericEditField
    EditField_9               matlab.ui.control.NumericEditField
    EditField_8               matlab.ui.control.NumericEditField
    EditField_6               matlab.ui.control.NumericEditField
    EditField_5               matlab.ui.control.NumericEditField
    EditField                 matlab.ui.control.NumericEditField
    CoupCoefxyxyxyxyxzLabel   matlab.ui.control.Label
    QCLabel                   matlab.ui.control.Label
    BunchIntervalsLabel       matlab.ui.control.Label
    NBunchLabel               matlab.ui.control.Label
    PZCorrelGeVmLabel         matlab.ui.control.Label
    SigPUncorrelLabel         matlab.ui.control.Label
    SigZmLabel                matlab.ui.control.Label
    ZposmLabel                matlab.ui.control.Label
    Nu2piLabel                matlab.ui.control.Label
    EtaPradLabel              matlab.ui.control.Label
    EtamLabel                 matlab.ui.control.Label
    AlphaLabel                matlab.ui.control.Label
    BetamLabel                matlab.ui.control.Label
    AngradLabel               matlab.ui.control.Label
    PosmLabel                 matlab.ui.control.Label
    NEmitmradLabel            matlab.ui.control.Label
    MomentumGeVLabel          matlab.ui.control.Label
    ValueYLabel               matlab.ui.control.Label
    ValueXLabel               matlab.ui.control.Label
    SectionsTab               matlab.ui.container.Tab
    BLLabel_9                 matlab.ui.control.Label
    BLLabel_8                 matlab.ui.control.Label
    BLLabel_7                 matlab.ui.control.Label
    SectionsListBox           matlab.ui.control.ListBox
    ClassesTab                matlab.ui.container.Tab
    DisplaySINGLEButton       matlab.ui.control.Button
    DisplayALLButton          matlab.ui.control.Button
    ToggleDisplayButton       matlab.ui.control.Button
    BLLabel_11                matlab.ui.control.Label
    BLLabel_10                matlab.ui.control.Label
    ClassListBox              matlab.ui.control.ListBox
  end

  
  properties (Access = private)
    Initial % Lucretia Initial structure
    i1 = 1 % Initial BEAMLINE element
    i2 = 1 % Final BEAMLINE element
    lastsearchele = 0 % last index found with search command
    twissplotopt(1,4) logical = [true true false false] % [betax betay etax etay]
    searchfield string {mustBeMember(searchfield,["Name","Class"])} = "Name" ;
    lineplot
    classes
    BeamlineDir string
    BeamlineFile
  end
  
  methods (Access = private)
    
    function LoadBeamlineFile(app,filename)
      evalin('base',sprintf('load %s',fullfile(app.BeamlineDir,filename)));
      for ifile=1:length(app.BeamlineFile)
        if string(get(app.BeamlineFile(ifile),'Text')) == string(filename)
          set(app.BeamlineFile(ifile),'Checked',true) ;
        else
          set(app.BeamlineFile(ifile),'Checked',false) ;
        end
      end
      app.RESETButtonPushed();
    end
    function refresh(app,forceupdate)
      %REFRESH Update text and plot field data choices
      global BEAMLINE PS GIRDER KLYSTRON SECTION
      persistent lastind lastdim lasttwissplotopt
      if ~exist('forceupdate','var')
        forceupdate=false;
      end
      ele = app.i1:app.i2 ;
      % Form sections list
      stat=SetElementSections(ele(1),ele(end));
      if stat
        sn=string([]);
        for isn=1:length(SECTION)
          sn(end+1)=sprintf("%-30s \t|\t %7.3f : %7.3f \t|\t %6d : %d",SECTION(isn).Name,BEAMLINE{SECTION(isn).Element(1)}.S,BEAMLINE{SECTION(isn).Element(2)}.S,SECTION(isn).Element);
        end
        app.SectionsListBox.Items=sn;
        app.SectionsListBox.ItemsData=1:length(SECTION);
        app.SectionsListBox.Value=1:length(SECTION);
      end
      if ~isempty(app.classes)
        cnames=fieldnames(app.classes);
        showclass=structfun(@(x) logical(x),app.classes); cnames=cnames(showclass);
        allclasses = arrayfun(@(x) BEAMLINE{x}.Class,ele,'UniformOutput',false);
        ele=ele(ismember(allclasses,cnames));
      end
      for itxt=1:length(ele)
        if isfield(BEAMLINE{ele(itxt)},'Section')
          dtxt(itxt) = sprintf("%6d : \t|\t %9.3fm \t|\t %-10s \t|\t %-15s \t|\t %-s",ele(itxt),BEAMLINE{ele(itxt)}.S,"("+BEAMLINE{ele(itxt)}.Class+")",SECTION(BEAMLINE{ele(itxt)}.Section).Name,BEAMLINE{ele(itxt)}.Name) ;
        else
          dtxt(itxt) = sprintf("%6d : \t|\t %9.3fm \t|\t %-10s \t|\t %-15s \t|\t %-s",ele(itxt),BEAMLINE{ele(itxt)}.S,"("+BEAMLINE{ele(itxt)}.Class+")","---",BEAMLINE{ele(itxt)}.Name) ;
        end
      end
      % dtxt = sprintf("%5d",ele) + " : " + arrayfun(@(x) BEAMLINE{x}.S,ele) + "m " + arrayfun(@(x) BEAMLINE{x}.Class,ele,'UniformOutput',false) + " " + arrayfun(@(x) BEAMLINE{x}.Name,ele,'UniformOutput',false);
      app.BEAMLINEText.Items = dtxt ;
      app.BEAMLINEText.ItemsData = ele ;
      if ~isempty(PS)
        psele=find(arrayfun(@(x) any(PS(x).Element>=app.i1) | any(PS(x).Element<=app.i2),1:length(PS)));
        if ~isempty(psele)
          app.PSListBox.Items = "PS " + psele(:) ;
          app.PSListBox.ItemsData = psele(:) ;
        else
          app.PSListBox.Items="";
          app.PSListBox.ItemsData=[];
        end
      else
        app.PSListBox.Items="";
        app.PSListBox.ItemsData=[];
      end
      if ~isempty(KLYSTRON)
        klyele=find(arrayfun(@(x) any(KLYSTRON(x).Element>=app.i1 | KLYSTRON(x).Element<=app.i2),1:length(KLYSTRON)));
        if ~isempty(klyele)
          app.KLYSTRONListBox.Items = "KLYSTRON " + klyele(:) ;
          app.KLYSTRONListBox.ItemsData = klyele(:) ;
        else
          app.KLYSTRONListBox.Items="";
          app.KLYSTRONListBox.ItemsData=[];
        end
      else
        app.KLYSTRONListBox.Items="";
        app.KLYSTRONListBox.ItemsData=[];
      end
      if ~isempty(GIRDER)
        girele = find(cellfun(@(x) any(x.Element>=app.i1 | x.Element<=app.i2),GIRDER)) ;
        if ~isempty(girele)
          app.GIRDERListBox.Items = "GIRDER " + girele(:) ;
          app.GIRDERListBox.ItemsData = girele(:) ;
        else
          app.GIRDERListBox.Items = "" ;
          app.GIRDERListBox.ItemsData = [] ;
        end
      else
        app.GIRDERListBox.Items = "" ;
        app.GIRDERListBox.ItemsData = [] ;
      end
      app.ElementText.Value="";
      app.BLLabel.Text = "BEAMLINE:" ;
      app.TrackFlagText.Value="";
      app.PSText.Value="";
      app.KLYSTRONText.Value="";
      app.GIRDERText.Value="";
      % Form Classes list
      classnames=string(unique(arrayfun(@(x) BEAMLINE{x}.Class,findcells(BEAMLINE,'Class',[],ele(1),ele(end)),'UniformOutput',false)));
      for iclass=1:length(classnames)
        if ~isfield(app.classes,classnames(iclass))
          app.classes.(classnames(iclass))=true;
        end
      end
      app.ClassListBox.ItemsData=classnames;
      idat=string([]);
      for iclass=1:length(classnames)
        if app.classes.(classnames(iclass))
          idat(iclass) = sprintf("%-20s \t|\t **",classnames(iclass)) ;
        else
          idat(iclass) = sprintf("%-20s \t|\t",classnames(iclass)) ;
        end
      end
      app.ClassListBox.Items = idat ;
      if forceupdate || isempty(lastind) || ~isequal(lastind,[app.i1,app.i2]) || app.XZMenu.Checked~=lastdim
        cla(app.LayoutAxes); drawnow;
        FP=FloorPlot;
        FP.axhan=app.LayoutAxes;
        if app.XZMenu.Checked
          FP.plot2d(1,app.i1,app.i2);
        else
          FP.plot2d(2,app.i1,app.i2);
        end
        lastdim=app.XZMenu.Checked;
      end
      if isempty(app.Initial)
        lastind=[app.i1,app.i2];
        return
      end
      if forceupdate || isempty(lastind) || ~isequal(lastind,[app.i1,app.i2]) || isempty(lasttwissplotopt) || ~isequal(lasttwissplotopt,app.twissplotopt)
        cla(app.TwissAxes);reset(app.TwissAxes); drawnow;
        if ~any(app.twissplotopt)
          lastind=[app.i1,app.i2];
          lasttwissplotopt=app.twissplotopt;
          return
        end
        if app.i1>1
          [stat,T]=GetTwiss(1,app.i1,app.Initial.x.Twiss,app.Initial.y.Twiss);
          if stat{1}~=1; errordlg('Twiss Calculation Error... Aborting.','Twiss Error'); end
          I=TwissToInitial(T,app.i1-1,app.Initial);
        else
          I=app.Initial;
        end
        [stat,T]=GetTwiss(app.i1,app.i2,I.x.Twiss,I.y.Twiss);
        if stat{1}~=1; errordlg('Twiss Calculation Error... Aborting.','Twiss Error'); end
        ax=app.TwissAxes;
        ltxt={};
        if app.twissplotopt(1)
          plot(ax,T.S,T.betax); hold(ax,'on');
          ltxt{1}='\beta_x';
        end
        if app.twissplotopt(2)
          plot(ax,T.S,T.betay); hold(ax,'on');
          ltxt{end+1}='\beta_y';
        end
        if any(app.twissplotopt(1:2))
          ylabel(ax,'\beta [m]');
          ltxt{end+1}='\eta_x';
        end
        if any(app.twissplotopt(3:4))
          yyaxis(ax,'right');
          ltxt{end+1}='\eta_y';
        end
        if app.twissplotopt(3)
          plot(ax,T.S,T.etax); hold(ax,'on');
        end
        if app.twissplotopt(4)
          plot(ax,T.S,T.etay);
        end
        if any(app.twissplotopt(3:4))
          ylabel(ax,'\eta [m]');
        end
        if any(app.twissplotopt(3:4))
          yyaxis(ax,'left');
        end
        hold(ax,'off');
        if ~isempty(ltxt)
          legend(ax,ltxt);
        end
        xlabel(ax,'S');
        lastind=[app.i1,app.i2];
      end
      lasttwissplotopt=app.twissplotopt;
    end
    function SetInitialTable(app)
      if isempty(app.Initial)
        return
      end
      I=app.Initial;
      app.EditField.Value = I.Momentum ;
      app.EditField_5.Value = I.x.NEmit ;
      app.EditField_6.Value = I.y.NEmit ;
      app.EditField_8.Value = I.x.pos ;
      app.EditField_9.Value = I.y.pos ;
      app.EditField_11.Value = I.x.ang ;
      app.EditField_12.Value = I.y.ang ;
      app.EditField_14.Value = I.x.Twiss.beta ;
      app.EditField_15.Value = I.y.Twiss.beta ;
      app.EditField_17.Value = I.x.Twiss.alpha ;
      app.EditField_18.Value = I.y.Twiss.alpha ;
      app.EditField_20.Value = I.x.Twiss.eta ;
      app.EditField_21.Value = I.y.Twiss.eta ;
      app.EditField_23.Value = I.x.Twiss.etap ;
      app.EditField_24.Value = I.y.Twiss.etap ;
      app.EditField_26.Value = I.x.Twiss.nu ;
      app.EditField_27.Value = I.y.Twiss.nu ;
      app.EditField_28.Value = I.zpos ;
      app.EditField_31.Value = I.sigz ;
      app.EditField_34.Value = I. SigPUncorrel ;
      app.EditField_37.Value = I.PZCorrel ;
      app.EditField_40.Value = I.NBunch ;
      app.EditField_43.Value = I.BunchInterval ;
      app.EditField_46.Value = I.Q ;
      app.EditField_49.Value = num2str([I.CoupCoef.xy I.CoupCoef.xpy I.CoupCoef.xyp I.CoupCoef.xpyp I.CoupCoef.xz]) ;
    end
    function classtextupdate(app)
      classnames = app.ClassListBox.ItemsData;
      idat=string([]);
      anysel=false;
      for iclass=1:length(classnames)
        if app.classes.(classnames(iclass))
          idat(iclass) = sprintf("%-20s \t|\t **",classnames(iclass)) ;
          anysel=true;
        else
          idat(iclass) = sprintf("%-20s \t|\t",classnames(iclass)) ;
        end
      end
      if ~anysel % ensure at least 1 class item selected
        app.classes.(classnames(1)) = true ;
        idat(1) = sprintf("%-20s \t|\t **",classnames(1)) ;
      end
      app.ClassListBox.Items = idat ;
      drawnow;
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, BeamlineDir)
      global BEAMLINE
      warning('off','MATLAB:legend:IgnoringExtraEntries');
      if exist('BeamlineDir','var')
        app.BeamlineDir = string(BeamlineDir) ;
        app.BeamlineDirMenu.Text = "Beamline Directory = " + app.BeamlineDir ;
        dd=dir(fullfile(app.BeamlineDir,'*.mat'));
        if ~isempty(app.BeamlineFile)
          for ifile=1:length(app.BeamlineFile)
            delete(app.BeamlineFile(ifile));
          end
        end
        app.BeamlineFile=[];
        for ifile=1:length(dd)
          app.BeamlineFile(ifile)=uimenu(app.LoadBeamlineMenu,'Text',dd(ifile).name,'MenuSelectedFcn',@(source,event) LoadBeamlineFile(app,dd(ifile).name));
        end
      end
      if isempty(BEAMLINE)
        return
      end
      if ~isfield(BEAMLINE{end},'S')
        SetSPositions(1,length(BEAMLINE));
      end
      if ~isfield(BEAMLINE{end},'Coordf')
        SetFloorCoordinates(1,length(BEAMLINE),zeros(1,6));
      end
      app.i2=length(BEAMLINE);
      
      try
        app.Initial = evalin('base','Initial') ;
        app.SetInitialTable();
      catch
      end
      app.refresh();
    end

    % Value changed function: BEAMLINEText
    function BEAMLINETextValueChanged(app, event)
      global BEAMLINE PS KLYSTRON GIRDER SECTION
      persistent lastline1 lastline2 lastline3 lastline4 aline1 aline2
      ele = app.BEAMLINEText.Value; ele_end=ele(end); ele=ele(1);
      app.DropDown.Value = "Name"; app.DropDownValueChanged(event); app.SearchText.Value = BEAMLINE{ele}.Name ; SearchTextValueChanged(app,event); drawnow;
      app.ClassListBox.Value = string(BEAMLINE{ele}.Class) ;
      app.ElementText.Value = formattedDisplayText(BEAMLINE{ele}) ;
      app.BLLabel.Text = "BEAMLINE{" + ele + "}:" ;
      if isfield(BEAMLINE{ele},'TrackFlag')
        app.TrackFlagText.Value = formattedDisplayText(BEAMLINE{ele}.TrackFlag);
      else
        app.TrackFlagText.Value = "" ;
      end
      if isfield(BEAMLINE{ele},'PS') && ~isempty(BEAMLINE{ele}.PS) && all(BEAMLINE{ele}.PS>0)
        app.PSText.Value=formattedDisplayText(PS(BEAMLINE{ele}.PS(1)));
        app.PSListBox.Value = BEAMLINE{ele}.PS(1) ;
      else
        app.PSText.Value="";
      end
      if isfield(BEAMLINE{ele},'Klystron') && ~isempty(BEAMLINE{ele}.Klystron) && all(BEAMLINE{ele}.Klystron>0)
        app.KLYSTRONText.Value=formattedDisplayText(KLYSTRON(BEAMLINE{ele}.Klystron(1)));
        app.KLYSTRONListBox.Value = BEAMLINE{ele}.Klystron(1) ;
      else
        app.KLYSTRONText.Value="";
      end
      if isfield(BEAMLINE{ele},'Girder') && ~isempty(BEAMLINE{ele}.Girder) && all(BEAMLINE{ele}.Girder>0)
        app.GIRDERText.Value=formattedDisplayText(GIRDER{BEAMLINE{ele}.Girder(1)});
        app.GIRDERListBox.Value = BEAMLINE{ele}.Girder(1) ;
      else
        app.GIRDERText.Value="";
      end
      app.lastsearchele=ele; % Flag search command to search forward of this point
      if ~isempty(lastline1)
        try
          delete(lastline1);
          delete(lastline2);
        catch
        end
      end
      if ~isempty(lastline3)
        try
          delete(lastline3);
          delete(lastline4);
          delete(aline1);
          delete(aline2);
        catch
        end
      end
      ax1=app.LayoutAxes;
      ax2=app.TwissAxes;
      lastline1 = line(ax1,BEAMLINE{ele}.Coordi(3).*ones(1,2),ax1.YLim,'Color','k','LineWidth',2,'LineStyle','--');
      lastline2 = line(ax2,BEAMLINE{ele}.S.*ones(1,2),ax2.YLim,'Color','k','LineWidth',2,'LineStyle','--');
      if ele_end>ele
        lastline3 = line(ax1,BEAMLINE{ele_end}.Coordf(3).*ones(1,2),ax1.YLim,'Color','k','LineWidth',2,'LineStyle','--');
        lastline4 = line(ax2,BEAMLINE{ele_end}.S.*ones(1,2),ax2.YLim,'Color','k','LineWidth',2,'LineStyle','--');
        hold(ax1,'on'); hold(ax2,'on');
        c=colororder;
        aline1=area(ax1,[BEAMLINE{ele}.Coordi(3),BEAMLINE{ele_end}.Coordf(3)],ax1.YLim(2).*ones(1,2),ax1.YLim(1),'FaceColor',c(1,:),'FaceAlpha',0.15);
        aline2=area(ax2,[BEAMLINE{ele}.S,BEAMLINE{ele_end}.S],ax2.YLim(2).*ones(1,2),ax2.YLim(1),'FaceColor',c(1,:),'FaceAlpha',0.15);
        hold(ax1,'off'); hold(ax2,'off');
      end
      leg=legend(app.TwissAxes);
      leg.String(startsWith(leg.String,"data"))='';
      % Section selection
      if isfield(BEAMLINE{ele},'Section')
        for isec=1:length(SECTION)
          if string(SECTION(isec).Name) == string(SECTION(BEAMLINE{ele}.Section).Name) && ismember(isec,app.SectionsListBox.ItemsData)
            app.SectionsListBox.Value=isec;
          end
        end
      else
        app.SectionsListBox.Value=app.SectionsListBox.ItemsData;
      end
    end

    % Button pushed function: RESETButton
    function RESETButtonPushed(app, event)
      global BEAMLINE
      app.i1=1;
      app.i2=length(BEAMLINE);
      cnames=fieldnames(app.classes);
      for iname=1:length(cnames)
        app.classes.(cnames{iname}) = true ;
      end
      app.refresh(true);
    end

    % Button pushed function: RESIZEButton
    function RESIZEButtonPushed(app, event)
      ele=app.BEAMLINEText.Value;
      if length(ele)>1
        app.i1=ele(1);
        app.i2=ele(end);
        app.refresh(true);
        app.BEAMLINEText.Value = app.BEAMLINEText.ItemsData(1) ;
        app.SearchTextValueChanged(event);
      end
    end

    % Button pushed function: SearchDownButton
    function SearchDownButtonPushed(app, event)
      global BEAMLINE
      name=char(app.SearchText.Value);
      if app.lastsearchele<app.i1 || app.lastsearchele>app.i2
        ind1=app.i1;
      else
        ind1=app.lastsearchele+1;
      end
      ind=findcells(BEAMLINE,char(app.searchfield),name,ind1,app.i2);
      if isempty(ind) && ind1~=app.i1
        app.lastsearchele=0;
        app.SearchDownButtonPushed(event);
        return
      elseif ~isempty(ind)
        app.BEAMLINEText.Value=ind(1);
        app.BEAMLINETextValueChanged(event);
        scroll(app.BEAMLINEText,ind(1));
        app.lastsearchele=ind(1);
      end
    end

    % Value changed function: SearchText
    function SearchTextValueChanged(app, event)
      global BEAMLINE
      value = app.SearchText.Value;
      if isempty(findcells(BEAMLINE,char(app.searchfield),value,app.i1,app.i2))
        app.SearchText.BackgroundColor='red';
      else
        app.SearchText.BackgroundColor='white';
      end
    end

    % Button pushed function: SearchUpButton
    function SearchUpButtonPushed(app, event)
      global BEAMLINE
      name=char(app.SearchText.Value);
      if app.lastsearchele<app.i1 || app.lastsearchele>app.i2
        ind2=app.i2;
      else
        ind2=app.lastsearchele-1;
      end
      ind=findcells(BEAMLINE,char(app.searchfield),name,app.i1,ind2);
      if isempty(ind) && ind2~=app.i2
        app.lastsearchele=app.i2;
        app.SearchUpButtonPushed(event);
        return
      elseif ~isempty(ind)
        app.BEAMLINEText.Value=ind(end);
        app.BEAMLINETextValueChanged(event);
        scroll(app.BEAMLINEText,ind(end));
        app.lastsearchele=ind(end);
      end
    end

    % Menu selected function: XZMenu
    function XZMenuSelected(app, event)
      if app.XZMenu.Checked
        app.XZMenu.Checked=false;
        app.YZMenu.Checked=true;
      else
        app.XZMenu.Checked=true;
        app.YZMenu.Checked=false;
      end
      app.refresh();
    end

    % Menu selected function: YZMenu
    function YZMenuSelected(app, event)
      if app.YZMenu.Checked
        app.YZMenu.Checked=false;
        app.XZMenu.Checked=true;
      else
        app.YZMenu.Checked=true;
        app.XZMenu.Checked=false;
      end
      app.refresh();
    end

    % Button pushed function: LoadfromWorkspaceButton
    function LoadfromWorkspaceButtonPushed(app, event)
      try
        app.Initial = evalin('base','Initial') ;
        app.SetInitialTable();
      catch
        errordlg('No Initial structure variable present in base workspace','Init Struct Missing');
      end
    end

    % Selection change function: PlotTabGroup
    function PlotTabGroupSelectionChanged(app, event)
      % selectedTab = app.PlotTabGroup.SelectedTab;
      % app.refresh(true);
    end

    % Menu selected function: beta_xMenu
    function beta_xMenuSelected(app, event)
      app.beta_xMenu.Checked=~app.beta_xMenu.Checked;
      app.twissplotopt(1)=app.beta_xMenu.Checked;
      app.refresh();
    end

    % Menu selected function: beta_yMenu
    function beta_yMenuSelected(app, event)
      app.beta_yMenu.Checked=~app.beta_yMenu.Checked;
      app.twissplotopt(2)=app.beta_yMenu.Checked;
      app.refresh();
    end

    % Menu selected function: eta_xMenu
    function eta_xMenuSelected(app, event)
      app.eta_xMenu.Checked=~app.eta_xMenu.Checked;
      app.twissplotopt(3)=app.eta_xMenu.Checked;
      app.refresh();
    end

    % Menu selected function: eta_yMenu
    function eta_yMenuSelected(app, event)
      app.eta_yMenu.Checked=~app.eta_yMenu.Checked;
      app.twissplotopt(4)=app.eta_yMenu.Checked;
      app.refresh();
    end

    % Value changed function: DropDown
    function DropDownValueChanged(app, event)
      value = app.DropDown.Value;
      app.searchfield = value ;
      app.SearchTextValueChanged(event) ;
    end

    % Value changed function: PSListBox
    function PSListBoxValueChanged(app, event)
      global PS
      value = app.PSListBox.Value;
      if ~isempty(PS) && value<=length(PS)
        app.BEAMLINEText.Value = PS(value).Element ;
        app.BEAMLINETextValueChanged(event) ;
        scroll(app.BEAMLINEText,app.BEAMLINEText.Value(1));
      end
    end

    % Value changed function: KLYSTRONListBox
    function KLYSTRONListBoxValueChanged(app, event)
      global KLYSTRON
      value = app.KLYSTRONListBox.Value;
      if ~isempty(KLYSTRON) && length(KLYSTRON)>=value
        app.BEAMLINEText.Value = KLYSTRON(value).Element ;
        app.BEAMLINETextValueChanged(event) ;
        scroll(app.BEAMLINEText,app.BEAMLINEText.Value(1));
      end
    end

    % Value changed function: GIRDERListBox
    function GIRDERListBoxValueChanged(app, event)
      global GIRDER
      value = app.GIRDERListBox.Value;
      if ~isempty(GIRDER) && length(GIRDER)>=value
        app.BEAMLINEText.Value = GIRDER{value}.Element ;
        app.BEAMLINETextValueChanged(event) ;
        scroll(app.BEAMLINEText,app.BEAMLINEText.Value(1));
      end
    end

    % Clicked callback: SectionsListBox
    function SectionsListBoxClicked(app, event)
      global SECTION
      drawnow
      val = event.Source.Value ;
      elelist=[];
      for ival=1:length(val)
        elelist=[elelist SECTION(val(ival)).Element(1):SECTION(val(ival)).Element(2)];
      end
      app.BEAMLINEText.Value = app.BEAMLINEText.ItemsData(ismember(app.BEAMLINEText.ItemsData,elelist)) ;
      app.BEAMLINETextValueChanged(event) ;
      scroll(app.BEAMLINEText,app.BEAMLINEText.Value(1));
    end

    % Button pushed function: ToggleDisplayButton
    function ToggleDisplayButtonPushed(app, event)
      class = app.ClassListBox.Value;
      for iclass=1:length(class)
        if app.classes.(class{iclass})
          app.classes.(class{iclass}) = false ;
        else
          app.classes.(class{iclass}) = true ;
        end
      end
      app.classtextupdate;
      app.refresh;
    end

    % Button pushed function: DisplayALLButton
    function DisplayALLButtonPushed(app, event)
      cnames = fieldnames(app.classes) ;
      for ifn=1:length(cnames)
        app.classes.(cnames{ifn}) = true ;
      end
      app.classtextupdate;
      app.refresh;
    end

    % Button pushed function: DisplaySINGLEButton
    function DisplaySINGLEButtonPushed(app, event)
      csel = app.ClassListBox.Value;
      cnames = fieldnames(app.classes) ;
      for ifn=1:length(cnames)
        app.classes.(cnames{ifn}) = false ;
      end
      app.classes.(csel{1}) = true ;
      app.classtextupdate;
      app.refresh;
    end

    % Clicked callback: ClassListBox
    function ClassListBoxClicked(app, event)
      item = event.InteractionInformation.Item;
      cname=app.ClassListBox.ItemsData{item};
      app.DropDown.Value = "Class"; app.DropDownValueChanged(event); app.SearchText.Value = cname ; SearchTextValueChanged(app,event); drawnow;
    end

    % Menu selected function: BeamlineDirMenu
    function BeamlineDirMenuSelected(app, event)
      d=uigetdir;
      if ~isequal(d,0)
        app.BeamlineDir = string(d) ;
        app.BeamlineDirMenu.Text = "Beamline Directory = " + app.BeamlineDir ;
        dd=dir(fullfile(app.BeamlineDir,'*.mat'));
        if ~isempty(app.BeamlineFile)
          for ifile=1:length(app.BeamlineFile)
            delete(app.BeamlineFile(ifile));
          end
        end
        app.BeamlineFile=[];
        for ifile=1:length(dd)
          app.BeamlineFile(ifile)=uimenu(app.LoadBeamlineMenu,'Text',dd(ifile).name,'MenuSelectedFcn',@(source,event) LoadBeamlineFile(app,dd(ifile).name));
        end
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      % Get the file path for locating images
      pathToMLAPP = fileparts(mfilename('fullpath'));
      app.LucretiaBEAMLINEUIFigure = uifigure('Visible', 'off');
      app.LucretiaBEAMLINEUIFigure.Name = 'Lucretia BEAMLINE';
      app.FileMenu = uimenu(app.LucretiaBEAMLINEUIFigure);
      app.FileMenu.Text = 'File';
      app.BeamlineDirMenu = uimenu(app.FileMenu);
      app.BeamlineDirMenu.MenuSelectedFcn = createCallbackFcn(app, @BeamlineDirMenuSelected, true);
      app.BeamlineDirMenu.Text = 'Beamline Directory = none';
      app.LoadBeamlineMenu = uimenu(app.FileMenu);
      app.LoadBeamlineMenu.Text = 'Load Beamline';
      app.LayoutMenu = uimenu(app.LucretiaBEAMLINEUIFigure);
      app.LayoutMenu.Text = 'Layout';
      app.XZMenu = uimenu(app.LayoutMenu);
      app.XZMenu.MenuSelectedFcn = createCallbackFcn(app, @XZMenuSelected, true);
      app.XZMenu.Checked = 'on';
      app.XZMenu.Text = 'X-Z';
      app.YZMenu = uimenu(app.LayoutMenu);
      app.YZMenu.MenuSelectedFcn = createCallbackFcn(app, @YZMenuSelected, true);
      app.YZMenu.Text = 'Y-Z';
      app.TwissMenu = uimenu(app.LucretiaBEAMLINEUIFigure);
      app.TwissMenu.Text = 'Twiss';
      app.beta_xMenu = uimenu(app.TwissMenu);
      app.beta_xMenu.MenuSelectedFcn = createCallbackFcn(app, @beta_xMenuSelected, true);
      app.beta_xMenu.Checked = 'on';
      app.beta_xMenu.Text = 'beta_x';
      app.beta_yMenu = uimenu(app.TwissMenu);
      app.beta_yMenu.MenuSelectedFcn = createCallbackFcn(app, @beta_yMenuSelected, true);
      app.beta_yMenu.Checked = 'on';
      app.beta_yMenu.Text = 'beta_y';
      app.eta_xMenu = uimenu(app.TwissMenu);
      app.eta_xMenu.MenuSelectedFcn = createCallbackFcn(app, @eta_xMenuSelected, true);
      app.eta_xMenu.Text = 'eta_x';
      app.eta_yMenu = uimenu(app.TwissMenu);
      app.eta_yMenu.MenuSelectedFcn = createCallbackFcn(app, @eta_yMenuSelected, true);
      app.eta_yMenu.Text = 'eta_y';
      app.TabGroup = uitabgroup(app.LucretiaBEAMLINEUIFigure);
      app.ElementTab = uitab(app.TabGroup);
      app.ElementTab.Title = 'Element';
      app.ElementText = uitextarea(app.ElementTab);
      app.TrackFlagText = uitextarea(app.ElementTab);
      app.BLLabel = uilabel(app.ElementTab);
      app.BLLabel.Text = 'BEAMLINE:';
      app.TrackFlagLabel = uilabel(app.ElementTab);
      app.TrackFlagLabel.Text = 'TrackFlag:';
      app.PSTab = uitab(app.TabGroup);
      app.PSTab.Title = 'PS';
      app.PSText = uitextarea(app.PSTab);
      app.PSListBox = uilistbox(app.PSTab);
      app.PSListBox.ValueChangedFcn = createCallbackFcn(app, @PSListBoxValueChanged, true);
      app.KLYSTRONTab = uitab(app.TabGroup);
      app.KLYSTRONTab.Title = 'KLYSTRON';
      app.KLYSTRONText = uitextarea(app.KLYSTRONTab);
      app.KLYSTRONListBox = uilistbox(app.KLYSTRONTab);
      app.KLYSTRONListBox.ValueChangedFcn = createCallbackFcn(app, @KLYSTRONListBoxValueChanged, true);
      app.GIRDERTab = uitab(app.TabGroup);
      app.GIRDERTab.Title = 'GIRDER';
      app.GIRDERText = uitextarea(app.GIRDERTab);
      app.GIRDERListBox = uilistbox(app.GIRDERTab);
      app.GIRDERListBox.ValueChangedFcn = createCallbackFcn(app, @GIRDERListBoxValueChanged, true);
      app.InitialTab = uitab(app.TabGroup);
      app.InitialTab.Title = 'Initial';
      app.GridLayout = uigridlayout(app.InitialTab);
      app.ValueXLabel = uilabel(app.GridLayout);
      app.ValueXLabel.Text = 'Value (X)';
      app.ValueYLabel = uilabel(app.GridLayout);
      app.ValueYLabel.Text = 'Value (Y)';
      app.MomentumGeVLabel = uilabel(app.GridLayout);
      app.MomentumGeVLabel.Text = 'Momentum [GeV]';
      app.NEmitmradLabel = uilabel(app.GridLayout);
      app.NEmitmradLabel.Text = 'NEmit [m-rad]';
      app.PosmLabel = uilabel(app.GridLayout);
      app.PosmLabel.Text = 'Pos [m]';
      app.AngradLabel = uilabel(app.GridLayout);
      app.AngradLabel.Text = 'Ang [rad]';
      app.BetamLabel = uilabel(app.GridLayout);
      app.BetamLabel.Text = 'Beta [m]';
      app.AlphaLabel = uilabel(app.GridLayout);
      app.AlphaLabel.Text = 'Alpha';
      app.EtamLabel = uilabel(app.GridLayout);
      app.EtamLabel.Text = 'Eta [m]';
      app.EtaPradLabel = uilabel(app.GridLayout);
      app.EtaPradLabel.Text = 'EtaP [rad]';
      app.Nu2piLabel = uilabel(app.GridLayout);
      app.Nu2piLabel.Text = 'Nu [*2pi]';
      app.ZposmLabel = uilabel(app.GridLayout);
      app.ZposmLabel.Text = 'Zpos [m]';
      app.SigZmLabel = uilabel(app.GridLayout);
      app.SigZmLabel.Text = 'SigZ [m]';
      app.SigPUncorrelLabel = uilabel(app.GridLayout);
      app.SigPUncorrelLabel.Text = 'SigPUncorrel';
      app.PZCorrelGeVmLabel = uilabel(app.GridLayout);
      app.PZCorrelGeVmLabel.Text = 'PZCorrel [GeV/m]';
      app.NBunchLabel = uilabel(app.GridLayout);
      app.NBunchLabel.Text = 'NBunch';
      app.BunchIntervalsLabel = uilabel(app.GridLayout);
      app.BunchIntervalsLabel.Text = 'BunchInterval [s]';
      app.QCLabel = uilabel(app.GridLayout);
      app.QCLabel.Text = 'Q [C]';
      app.CoupCoefxyxyxyxyxzLabel = uilabel(app.GridLayout);
      app.CoupCoefxyxyxyxyxzLabel.Text = 'CoupCoef  [xy,x''y,xy'',x''y'',xz]';
      app.EditField = uieditfield(app.GridLayout, 'numeric');
      app.EditField_5 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_6 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_8 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_9 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_11 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_12 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_14 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_15 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_17 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_18 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_20 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_21 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_23 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_24 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_26 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_27 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_28 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_31 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_34 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_37 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_40 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_43 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_46 = uieditfield(app.GridLayout, 'numeric');
      app.EditField_49 = uieditfield(app.GridLayout, 'text');
      app.EditField_49.Value = '0 , 0 , 0 , 0 , 0';
      app.LoadfromWorkspaceButton = uibutton(app.GridLayout, 'push');
      app.LoadfromWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromWorkspaceButtonPushed, true);
      app.LoadfromWorkspaceButton.Text = 'Load from Workspace';
      app.SectionsTab = uitab(app.TabGroup);
      app.SectionsTab.Title = 'Sections';
      app.SectionsListBox = uilistbox(app.SectionsTab);
      app.SectionsListBox.Multiselect = 'on';
      app.SectionsListBox.ClickedFcn = createCallbackFcn(app, @SectionsListBoxClicked, true);
      app.SectionsListBox.Value = {'Item 1'};
      app.BLLabel_7 = uilabel(app.SectionsTab);
      app.BLLabel_7.Text = 'Section Name';
      app.BLLabel_8 = uilabel(app.SectionsTab);
      app.BLLabel_8.Text = 'S Range (m)';
      app.BLLabel_9 = uilabel(app.SectionsTab);
      app.BLLabel_9.Text = 'Index Range';
      app.ClassesTab = uitab(app.TabGroup);
      app.ClassesTab.Title = 'Classes';
      app.ClassListBox = uilistbox(app.ClassesTab);
      app.ClassListBox.Items = {'QUAD', 'SEXT', 'SBEN', 'LCAV', 'TCAV', 'XCOR', 'YCOR'};
      app.ClassListBox.Multiselect = 'on';
      app.ClassListBox.ClickedFcn = createCallbackFcn(app, @ClassListBoxClicked, true);
      app.ClassListBox.Value = {'QUAD'};
      app.BLLabel_10 = uilabel(app.ClassesTab);
      app.BLLabel_10.Text = 'Class Name';
      app.BLLabel_11 = uilabel(app.ClassesTab);
      app.BLLabel_11.Text = 'Display?';
      app.ToggleDisplayButton = uibutton(app.ClassesTab, 'push');
      app.ToggleDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleDisplayButtonPushed, true);
      app.ToggleDisplayButton.Text = 'Toggle Display';
      app.DisplayALLButton = uibutton(app.ClassesTab, 'push');
      app.DisplayALLButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayALLButtonPushed, true);
      app.DisplayALLButton.Text = 'Display ALL';
      app.DisplaySINGLEButton = uibutton(app.ClassesTab, 'push');
      app.DisplaySINGLEButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySINGLEButtonPushed, true);
      app.DisplaySINGLEButton.Text = 'Display SINGLE';
      app.BEAMLINEPanel = uipanel(app.LucretiaBEAMLINEUIFigure);
      app.BEAMLINEPanel.Title = 'BEAMLINE';
      app.BEAMLINEText = uilistbox(app.BEAMLINEPanel);
      app.BEAMLINEText.ItemsData = [2 3 4 5];
      app.BEAMLINEText.Multiselect = 'on';
      app.BEAMLINEText.ValueChangedFcn = createCallbackFcn(app, @BEAMLINETextValueChanged, true);
      app.BEAMLINEText.Value = 2;
      app.SearchText = uieditfield(app.BEAMLINEPanel, 'text');
      app.SearchText.ValueChangedFcn = createCallbackFcn(app, @SearchTextValueChanged, true);
      app.SearchText.Value = '---';
      app.SearchDownButton = uibutton(app.BEAMLINEPanel, 'push');
      app.SearchDownButton.ButtonPushedFcn = createCallbackFcn(app, @SearchDownButtonPushed, true);
      app.SearchDownButton.Icon = fullfile(pathToMLAPP, 'darrow.svg');
      app.SearchDownButton.Text = '';
      app.RESIZEButton = uibutton(app.BEAMLINEPanel, 'push');
      app.RESIZEButton.ButtonPushedFcn = createCallbackFcn(app, @RESIZEButtonPushed, true);
      app.RESIZEButton.Text = 'RESIZE';
      app.RESETButton = uibutton(app.BEAMLINEPanel, 'push');
      app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
      app.RESETButton.Text = 'RESET';
      app.SearchUpButton = uibutton(app.BEAMLINEPanel, 'push');
      app.SearchUpButton.ButtonPushedFcn = createCallbackFcn(app, @SearchUpButtonPushed, true);
      app.SearchUpButton.Icon = fullfile(pathToMLAPP, 'uarrow.svg');
      app.SearchUpButton.Text = '';
      app.DropDown = uidropdown(app.BEAMLINEPanel);
      app.DropDown.Items = {'Name', 'Class'};
      app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
      app.DropDown.Value = 'Name';
      app.BLLabel_2 = uilabel(app.BEAMLINEPanel);
      app.BLLabel_2.Text = 'Ele #';
      app.BLLabel_3 = uilabel(app.BEAMLINEPanel);
      app.BLLabel_3.Text = 'S (m)';
      app.BLLabel_4 = uilabel(app.BEAMLINEPanel);
      app.BLLabel_4.Text = 'Class';
      app.BLLabel_5 = uilabel(app.BEAMLINEPanel);
      app.BLLabel_5.Text = 'Name';
      app.BLLabel_6 = uilabel(app.BEAMLINEPanel);
      app.BLLabel_6.Text = 'Section';
      app.PlotTabGroup = uitabgroup(app.LucretiaBEAMLINEUIFigure);
      app.PlotTabGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTabGroupSelectionChanged, true);
      app.LayoutTab = uitab(app.PlotTabGroup);
      app.LayoutTab.Title = 'Layout';
      app.LayoutAxes = uiaxes(app.LayoutTab);
      xlabel(app.LayoutAxes, 'X')
      ylabel(app.LayoutAxes, 'Y')
      zlabel(app.LayoutAxes, 'Z')
      app.TwissTab = uitab(app.PlotTabGroup);
      app.TwissTab.Title = 'Twiss';
      app.TwissAxes = uiaxes(app.TwissTab);
      xlabel(app.TwissAxes, 'X')
      ylabel(app.TwissAxes, 'Y')
      zlabel(app.TwissAxes, 'Z')
      % Show the figure after all components are created
      app.LucretiaBEAMLINEUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = BeamlineViewer(varargin)

      runningApp = getRunningApp(app);

      % Check for running singleton app
      if isempty(runningApp)

        % Create UIFigure and components
        createComponents(app)

        % Register the app with App Designer
        registerApp(app, app.LucretiaBEAMLINEUIFigure)

        % Execute the startup function
        runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
      else

        % Focus the running singleton app
        figure(runningApp.LucretiaBEAMLINEUIFigure)

        app = runningApp;
      end

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.LucretiaBEAMLINEUIFigure)
    end
  end
end